classdef HOD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RightHandGaugeLabel       matlab.ui.control.Label
        RightHandGauge            matlab.ui.control.LinearGauge
        Lamp                      matlab.ui.control.Lamp
        CommunicationSwitchLabel  matlab.ui.control.Label
        CommunicationSwitch       matlab.ui.control.Switch
        LeftHandGaugeLabel        matlab.ui.control.Label
        LeftHandGauge             matlab.ui.control.LinearGauge
        PortDropDownLabel         matlab.ui.control.Label
        Port                      matlab.ui.control.DropDown
        BaudrateDropDownLabel     matlab.ui.control.Label
        Baudrate                  matlab.ui.control.DropDown
        Image                     matlab.ui.control.Image
        LThresholdSpinnerLabel    matlab.ui.control.Label
        LThresholdSpinner         matlab.ui.control.Spinner
        RThresholdSpinnerLabel    matlab.ui.control.Label
        RThresholdSpinner         matlab.ui.control.Spinner
        DisplayEditFieldLabel     matlab.ui.control.Label
        DisplayEditField          matlab.ui.control.EditField
        Lamp_left                 matlab.ui.control.Lamp
        Lamp_right                matlab.ui.control.Lamp
        PathEditFieldLabel        matlab.ui.control.Label
        PathEditField             matlab.ui.control.EditField
        DatasaveSwitchLabel       matlab.ui.control.Label
        DatasaveSwitch            matlab.ui.control.Switch
        RightPanel                matlab.ui.container.Panel
        UIAxesleft                matlab.ui.control.UIAxes
        UIAxesright               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        ser ;% Description
        RX_once;%一次接收的数据
        RX_Date;%接收的所以数据
        Timer_id;
        Timer_id2;
        Left_pressure_data=[];
        Right_pressure_data=[];
        left_state=[];
        right_state=[];
    end
    
    
    methods (Access = public)
        function  BytesAvailableFcn(app, src, event)
            global Left_pressure_data
            global Right_pressure_data
            n = src.BytesAvailable;%获取接收到的字符个数
            if n>0
                app.RX_once=fread(src,n,'char');%读取函数，读取后矩阵为一列
                app.RX_Date =strcat(app.RX_Date, app.RX_once');%字符串拼接，需要转置化为一行
                app.DisplayEditField.Value= mat2str(app.RX_once);%textarea的设置格式为cell,或单行字符串
                if (app.RX_once(1)==255)&(app.RX_once(2)==255)
                    if (length(Left_pressure_data)<100)
                        Left_pressure_data=[Left_pressure_data;app.RX_once(3)];
                        
                    else
                        Left_pressure_data=circshift(Left_pressure_data, 1);
                        Left_pressure_data(100)=app.RX_once(3);
                    end
                    
                    if (length(Right_pressure_data)<100)
                        Right_pressure_data=[Right_pressure_data;app.RX_once(4)];
                        
                    else
                        Right_pressure_data=circshift(Right_pressure_data, 1);
                        Right_pressure_data(100)=app.RX_once(4);
                    end
                end
            end
        end
        
        
        function timer_handler(app)
            %执行定时器任务
            global Left_pressure_data
            global Right_pressure_data
            
            
            plot(app.UIAxesleft,Left_pressure_data);
            hold(app.UIAxesleft,'on');   % 这样就可以hold住了
            plot(app.UIAxesleft,smooth(Left_pressure_data));
            legend(app.UIAxesleft,"Raw data","Smoothed data")
            hold(app.UIAxesleft,'off');
            
            
            plot(app.UIAxesright,Right_pressure_data);
            hold(app.UIAxesright,'on');   % 这样就可以hold住了
            plot(app.UIAxesright,smooth(Right_pressure_data));
            legend(app.UIAxesright,"Raw data","Smoothed data")
            hold(app.UIAxesright,'off');
            
            try
                if strcmp(app.DatasaveSwitch.Value,"On")
                    dlmwrite(app.PathEditField.Value+"left.csv",Left_pressure_data(length(Left_pressure_data)),'-append')
                    dlmwrite(app.PathEditField.Value+"right.csv",Right_pressure_data(length(Right_pressure_data)),'-append')
                end
                
            catch ME
                
            end
            
            
        end
        
        function timer_handler2(app)
            %执行定时器任务
            global Left_pressure_data
            global Right_pressure_data
            
            if mean (Left_pressure_data)<app.LThresholdSpinner.Value
                app.left_state=circshift(app.left_state, 1);
                app.left_state(15)=0;
            else
                app.left_state=circshift(app.left_state, 1);
                app.left_state(15)=1;
            end
            
            app.LeftHandGauge.Value=(mean(app.left_state)*100);
            
            if mean(app.left_state)*100<=0
                app.Lamp_left.Color=[1,0,0];
            else
                app.Lamp_left.Color=[0,1,0];
            end
            
            
            if mean (Right_pressure_data)<app.RThresholdSpinner.Value
                app.right_state=circshift(app.right_state, 1);
                app.right_state(15)=0;
            else
                app.right_state=circshift(app.right_state, 1);
                app.right_state(15)=1;
            end
            
            app.RightHandGauge.Value=(mean(app.right_state)*100);
            
            if mean(app.right_state)*100<=0
                app.Lamp_right.Color=[1,0,0];
            else
                app.Lamp_right.Color=[0,1,0];
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fclose("all")
            delete("all")
            com_list=serialportlist("all")
            try
                app.Port.Items=com_list
                app.PathEditField.Value=pwd+"\"
            catch ME
            end
            
            if  isempty(com_list) %判断是否插入设备
                msgbox("No device found")
            end
            
            
            app.Timer_id = timer;
            app.Timer_id.StartDelay = 0.01; % 开启的延时生效时间
            app.Timer_id.Period = 0.2;% 周期
            app.Timer_id.ExecutionMode = 'fixedDelay';
            app.Timer_id.TimerFcn = @(~, ~) timer_handler(app);
            
            app.Timer_id2 = timer;
            app.Timer_id2.StartDelay = 0.01; % 开启的延时生效时间
            app.Timer_id2.Period = 1;% 周期
            app.Timer_id2.ExecutionMode = 'fixedDelay';
            app.Timer_id2.TimerFcn = @(~, ~) timer_handler2(app);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Port
        function PortValueChanged(app, event)
            value = app.Port.Value;
            
        end

        % Value changed function: CommunicationSwitch
        function CommunicationSwitchValueChanged(app, event)
            %global app.ser
            value = app.CommunicationSwitch.Value;
            app.Lamp.Color=[0,1,0]
            
            if strcmp(value,"On")
                
               try
                app.ser=serial(app.Port.Value,'BaudRate',str2num(app.Baudrate.Value));
                % 设置串口中断,接收到1个字节中断
                
                app.ser.DataBits=8;%设置数据长度
                app.ser.StopBits=1;%设置停止位长度
                app.ser.InputBufferSize=1024;%设置输入缓冲区大小为1M
                app.ser.BytesAvailableFcnMode='byte';  %串口事件回调设置
                app.ser.Terminator='CR/LF';
                %app.ser.BytesAvailableFcnCount=20; %输入缓冲区存在10个字节触发回调函数
                app.ser.BytesAvailableFcn={@app.BytesAvailableFcn};%回调函数的指定
                fopen(app.ser);
                
                app.Lamp.Color=[0,1,0]
                % 定时器启动
                start(app.Timer_id);
                start(app.Timer_id2);
               catch
                   msgbox("Please check the equipment")
               end
               
            else
                app.Lamp.Color=[1,0,0]
                fclose(app.ser);
                delete(app.ser);
                stop(app.Timer_id)
                stop(app.Timer_id2)
                clear all
                clc
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightHandGaugeLabel = uilabel(app.LeftPanel);
            app.RightHandGaugeLabel.Text = 'Right-Hand';
            app.RightHandGauge = uigauge(app.LeftPanel, 'linear');
            app.Lamp = uilamp(app.LeftPanel);
            app.CommunicationSwitchLabel = uilabel(app.LeftPanel);
            app.CommunicationSwitchLabel.Text = 'Communication ';
            app.CommunicationSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CommunicationSwitch.ValueChangedFcn = createCallbackFcn(app, @CommunicationSwitchValueChanged, true);
            app.LeftHandGaugeLabel = uilabel(app.LeftPanel);
            app.LeftHandGaugeLabel.Text = 'Left-Hand';
            app.LeftHandGauge = uigauge(app.LeftPanel, 'linear');
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.Port = uidropdown(app.LeftPanel);
            app.Port.Items = {'', ''};
            app.Port.ValueChangedFcn = createCallbackFcn(app, @PortValueChanged, true);
            app.Port.Value = '';
            app.BaudrateDropDownLabel = uilabel(app.LeftPanel);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.Baudrate = uidropdown(app.LeftPanel);
            app.Baudrate.Items = {'9600', '38400', '115200'};
            app.Baudrate.Value = '9600';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'TJU.png';
            app.LThresholdSpinnerLabel = uilabel(app.LeftPanel);
            app.LThresholdSpinnerLabel.Text = 'L-Threshold';
            app.LThresholdSpinner = uispinner(app.LeftPanel);
            app.LThresholdSpinner.Limits = [0 255];
            app.LThresholdSpinner.Value = 50;
            app.RThresholdSpinnerLabel = uilabel(app.LeftPanel);
            app.RThresholdSpinnerLabel.Text = 'R-Threshold';
            app.RThresholdSpinner = uispinner(app.LeftPanel);
            app.RThresholdSpinner.Limits = [0 255];
            app.RThresholdSpinner.Value = 50;
            app.DisplayEditFieldLabel = uilabel(app.LeftPanel);
            app.DisplayEditFieldLabel.Text = 'Display';
            app.DisplayEditField = uieditfield(app.LeftPanel, 'text');
            app.Lamp_left = uilamp(app.LeftPanel);
            app.Lamp_right = uilamp(app.LeftPanel);
            app.PathEditFieldLabel = uilabel(app.LeftPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.LeftPanel, 'text');
            app.DatasaveSwitchLabel = uilabel(app.LeftPanel);
            app.DatasaveSwitchLabel.Text = 'Data save';
            app.DatasaveSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesleft = uiaxes(app.RightPanel);
            title(app.UIAxesleft, 'Left  Pressure Sensor')
            xlabel(app.UIAxesleft, 'Index')
            ylabel(app.UIAxesleft, 'Pressure')
            app.UIAxesright = uiaxes(app.RightPanel);
            title(app.UIAxesright, 'Right  Pressure Sensor')
            xlabel(app.UIAxesright, 'Index')
            ylabel(app.UIAxesright, 'Pressure')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HOD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end